classdef plot_app_gabino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        RunItButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunItButton
        function RunItButtonPushed(app, event)
dx = 0.5

y = ([10, 20, 30, 40])
x = ([25, 25, 25, 25])

l = plot(app.UIAxes,x,y);

a = app.UIAxes
f = a.Parent;

f.Name = 'Gabino'
f.NumberTitle = 'off'

fprintf('SOMEBODY CLICKED ON ME.\n');

l.Color = ['red']
l.LineStyle = 'none';
l.LineWidth = 5;
l.Marker = 'o';


a.XLim = [0 50];
a.YLim = [0 50];

a.Box = 'on'
fps = 60
k=0

for k = 1:1000
    if l.XData(1) > a.XLim(2)
        dx = -dx
    else
        if l.XData(1) < a.XLim(1)
            dx = -dx
        end
    end
    l.XData = l.XData + dx;
    pause(1/fps);
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.RunItButton = uibutton(app.UIFigure, 'push');
            app.RunItButton.ButtonPushedFcn = createCallbackFcn(app, @RunItButtonPushed, true);
            app.RunItButton.Text = 'Run It!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_app_gabino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end